classdef TelemedicineUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label                      matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        PATIENTINTAKETab           matlab.ui.container.Tab
        PatientDetailsPanel_2      matlab.ui.container.Panel
        GenderEditField_2          matlab.ui.control.EditField
        GenderEditField_2Label     matlab.ui.control.Label
        NameEditField_2            matlab.ui.control.EditField
        NameEditField_2Label       matlab.ui.control.Label
        HbA1cEditField_2           matlab.ui.control.EditField
        HbA1cEditField_2Label      matlab.ui.control.Label
        AgeEditField_2             matlab.ui.control.EditField
        AgeEditField_2Label        matlab.ui.control.Label
        AIDIAGNOSTICSTab           matlab.ui.container.Tab
        SeverityLamp_2             matlab.ui.control.Lamp
        SeverityLampLabel          matlab.ui.control.Label
        RunAIDiagnosisButton       matlab.ui.control.Button
        UploadScanButton           matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        EXPLAINABILITYHUBTab       matlab.ui.container.Tab
        LesionTextArea             matlab.ui.control.TextArea
        LesionCountTextAreaLabel   matlab.ui.control.Label
        UIAxes3                    matlab.ui.control.UIAxes
        TELEMEDICINESIMULATIONTab  matlab.ui.container.Tab
        GenReportBtnButton         matlab.ui.control.Button
        MetricsAxes                matlab.ui.control.UIAxes
        ADMINSETTINGSTab           matlab.ui.container.Tab
        SMTPEditField              matlab.ui.control.EditField
        SMTPEditFieldLabel         matlab.ui.control.Label
        LoadModelButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CurrentImagePath % Holds path to selected funds image
        
        CurrentImg % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Set the entire app figure background to pure white
            app.UIFigure.Color = [1, 1, 1];
           

            % 2. Set background colors for your main content panels/containers 
            % (Replace with your exact panel names from App Designer)
            % app.PatientIntakePanel.BackgroundColor = [0.97, 0.97, 0.98]; % Light card gray
            % app.AIDiagnosticPanel.BackgroundColor = [0.97, 0.97, 0.98];
            % app.ExplainabilityPanel.BackgroundColor = [0.97, 0.97, 0.98];
            % app.TelemedicinePanel.BackgroundColor = [0.97, 0.97, 0.98];

            % 3. Set background color for text areas (like Lesion Count box)
            if isprop(app, 'LesionText')
                app.LesionText.BackgroundColor = [0.98, 0.98, 0.99];
            end

            % 4. Style Status and Quality Indicators to match your screenshot colors
            % Soft green for passing quality checks
            % app.QualityPanel.BackgroundColor = [0.85, 0.95, 0.85]; 

            % Soft amber/yellow for warning status (e.g., Referred / Severe DR)
            % app.StatusLabel.BackgroundColor = [1.0, 0.94, 0.70]; 

            % 5. Style Action Buttons (Deep Medical Blue/Teal)
            % app.UploadScanButton.BackgroundColor = [0.12, 0.47, 0.67];
            % app.RunAIDiagnosisButton.BackgroundColor = [0.12, 0.47, 0.67];
            % app.GenReportBtn.BackgroundColor = [0.12, 0.47, 0.67];
            % Set the main window background color
            app.UIFigure.Color = [0.94, 0.96, 0.98]; % Soft medical off-white/light blue-gray

            % Style panels to create clean white container cards against the background
            % (Uncomment and apply to your specific panel names if using custom panels)
            % app.PatientIntakePanel.BackgroundColor = [1, 1, 1];
            % app.AIDiagnosticPanel.BackgroundColor = [1, 1, 1];
            % app.ExplainabilityPanel.BackgroundColor = [1, 1, 1];
            % app.TelemedicinePanel.BackgroundColor = [1, 1, 1];

            % Customize tab group header color theme if desired
            %app.TabGroup.ForegroundColor = [0.08, 0.22, 0.32]; 

            % Style status text and labels for high readability
            if isprop(app, 'StatusLabel')
                app.StatusLabel.FontColor = [0.8, 0.2, 0.2]; % Highlighting warning status in clinical amber/red
            end
            % Set the main window background to clean white
            app.UIFigure.Color = [1, 1, 1];


            % Add Header Title Text
            %app.HeaderTitle.Position = [20, 10, 700, 30];
            %app.HeaderTitle.Text = 'NEUROFORGE DIAGNOSTICS: AI-POWERED DIABETIC RETINOPATHY SCREENING';
            %app.HeaderTitle.FontSize = 14;
            %app.HeaderTitle.FontWeight = 'bold';
            %app.HeaderTitle.FontColor = [1, 1, 1];
            % Set the main window background to clean white
            app.UIFigure.Color = [1, 1, 1];


            %% Explainability Hub Tab Setup


            %app.LesionText.Value = {'LESIONS DETECTED:', '• Microaneurysms: 18', '• Hemorrhages: 4', '• Hard Exudates: 5'};
            %app.LesionText.BackgroundColor = [1, 1, 1]; % Match your background

            % Telemedicine Simulation Tab Setup
            %app.MetricsAxes = uiaxes(app.TELEMEDICINESIMULATIONTab);
            %app.MetricsAxes.Position = [30, 150, 350, 300];
            %title(app.MetricsAxes, 'Simulink System Metrics');

            %app.GenReportBtn = uibutton(app.TELEMEDICINESIMULATIONTab, 'push');
            %app.GenReportBtn.Position = [410, 350, 180, 40];
            %app.GenReportBtn.Text = 'Generate Doctor Report';

            % Admin Settings Tab Setup
            %app.LoadModelBtn = uibutton(app.ADMINSETTINGSTab, 'push');
            %app.LoadModelBtn.Position = [50, 400, 200, 35];
            %app.LoadModelBtn.Text = 'Load trainedDRNet.mat';

            %app.SMTPField = uieditfield(app.ADMINSETTINGSTab, 'text');
            %app.SMTPField.Position = [50, 330, 250, 30];
            %app.SMTPField.Placeholder = 'SMTP Server / Credentials';

           
        end

        % Button pushed function: UploadScanButton
        function UploadScanButtonPushed(app, event)
            % Button pushed function: UploadScanButton
            
                [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp','Retina Scan Images (*.jpg, *.png, *.jpeg)'});
                if ~isequal(file, 0)
                    fullPath = fullfile(path, file);
                    app.CurrentImg = imread(fullPath);
                    imshow(app.CurrentImg, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'RAW IMAGE');
                end
            
        end

        % Button pushed function: RunAIDiagnosisButton
        function RunAIDiagnosisButtonPushed(app, event)
            % Button pushed function: RunAIDiagnosisButton
           
                if isempty(app.CurrentImg)
                    uialert(app.UIFigure, 'Please upload a retinal scan first.', 'Missing Image');
                    return;
                end

                % CLAHE Image Enhancement
                grayImg = rgb2gray(app.CurrentImg);
                enhancedImg = adapthisteq(grayImg);
                imshow(enhancedImg, 'Parent', app.UIAxes2);
                title(app.UIAxes2, 'ENHANCED (CLAHE)');

                % Populate Lesion Text Readouts
                app.LesionTextArea.Value = { ...
    'LESIONS DETECTED:'; ...
    '• Microaneurysms: 18'; ...
    '• Hemorrhages: 4'; ...
    '• Hard Exudates: 5' ...
};
                    app.LesionTextArea.Value = { ...
                    '• Microaneurysms: 18';
                    '• Hemorrhages: 4';
                    '• Hard Exudates: 5'
                    };

                % Render Grad-CAM Heatmap Overlay
imshow(app.CurrentImg, 'Parent', app.UIAxes3);
hold(app.UIAxes3, 'on');
heatmapOverlay = rand(size(grayImg)); % Replace with real Grad-CAM output matrix from network
hMap = imshow(heatmapOverlay, 'Parent', app.UIAxes3);
set(hMap, 'AlphaData', 0.4);
colormap(app.UIAxes3, jet);
title(app.UIAxes3, 'Grad-CAM Heatmap Overlay');
hold(app.UIAxes3, 'off');
        end

        % Button pushed function: GenReportBtnButton
        function GenReportBtnButtonPushed(app, event)

    % Plot telemedicine transmission or system metrics
    simData = rand(1, 50); 
    plot(app.MetricsAxes, simData, 'LineWidth', 1.5);
    title(app.MetricsAxes, 'Simulink System Transmission Metrics');
    xlabel(app.MetricsAxes, 'Time Step');
    ylabel(app.MetricsAxes, 'Packet Latency / Bandwidth');
    grid(app.MetricsAxes, 'on');
    
    uialert(app.UIFigure, 'Telemedicine report generated and transmission simulated successfully.', 'Simulation Complete');

        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            
                [file, path] = uigetfile('*.mat', 'Select Trained DR Model');
                if ~isequal(file, 0)
                    app.LoadedModel = load(fullfile(path, file));
                    uialert(app.UIFigure, 'Model loaded successfully into system memory.', 'Admin Settings');
                end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PATIENTINTAKETab = uitab(app.TabGroup);
            app.PATIENTINTAKETab.Title = 'PATIENT INTAKE';
            app.PatientDetailsPanel_2 = uipanel(app.PATIENTINTAKETab);
            app.PatientDetailsPanel_2.Title = 'Patient Details';
            app.AgeEditField_2Label = uilabel(app.PatientDetailsPanel_2);
            app.AgeEditField_2Label.Text = 'Age';
            app.AgeEditField_2 = uieditfield(app.PatientDetailsPanel_2, 'text');
            app.HbA1cEditField_2Label = uilabel(app.PatientDetailsPanel_2);
            app.HbA1cEditField_2Label.Text = 'HbA1c';
            app.HbA1cEditField_2 = uieditfield(app.PatientDetailsPanel_2, 'text');
            app.NameEditField_2Label = uilabel(app.PatientDetailsPanel_2);
            app.NameEditField_2Label.Text = 'Name';
            app.NameEditField_2 = uieditfield(app.PatientDetailsPanel_2, 'text');
            app.GenderEditField_2Label = uilabel(app.PatientDetailsPanel_2);
            app.GenderEditField_2Label.Text = 'Gender';
            app.GenderEditField_2 = uieditfield(app.PatientDetailsPanel_2, 'text');
            app.AIDIAGNOSTICSTab = uitab(app.TabGroup);
            app.AIDIAGNOSTICSTab.Title = 'AI DIAGNOSTICS';
            app.UIAxes = uiaxes(app.AIDIAGNOSTICSTab);
            title(app.UIAxes, 'RawImage')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.AIDIAGNOSTICSTab);
            title(app.UIAxes2, 'enhanced image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UploadScanButton = uibutton(app.AIDIAGNOSTICSTab, 'push');
            app.UploadScanButton.ButtonPushedFcn = createCallbackFcn(app, @UploadScanButtonPushed, true);
            app.UploadScanButton.Text = 'Upload Scan';
            app.RunAIDiagnosisButton = uibutton(app.AIDIAGNOSTICSTab, 'push');
            app.RunAIDiagnosisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAIDiagnosisButtonPushed, true);
            app.RunAIDiagnosisButton.Text = 'Run AI Diagnosis';
            app.SeverityLampLabel = uilabel(app.AIDIAGNOSTICSTab);
            app.SeverityLampLabel.Text = 'Severity Lamp';
            app.SeverityLamp_2 = uilamp(app.AIDIAGNOSTICSTab);
            app.EXPLAINABILITYHUBTab = uitab(app.TabGroup);
            app.EXPLAINABILITYHUBTab.Title = 'EXPLAINABILITY HUB';
            app.UIAxes3 = uiaxes(app.EXPLAINABILITYHUBTab);
            title(app.UIAxes3, 'Grad CAM')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.LesionCountTextAreaLabel = uilabel(app.EXPLAINABILITYHUBTab);
            app.LesionCountTextAreaLabel.Text = 'Lesion Count';
            app.LesionTextArea = uitextarea(app.EXPLAINABILITYHUBTab);
            app.TELEMEDICINESIMULATIONTab = uitab(app.TabGroup);
            app.TELEMEDICINESIMULATIONTab.Title = 'TELEMEDICINE SIMULATION';
            app.MetricsAxes = uiaxes(app.TELEMEDICINESIMULATIONTab);
            title(app.MetricsAxes, 'Metrics')
            xlabel(app.MetricsAxes, 'X')
            ylabel(app.MetricsAxes, 'Y')
            zlabel(app.MetricsAxes, 'Z')
            app.GenReportBtnButton = uibutton(app.TELEMEDICINESIMULATIONTab, 'push');
            app.GenReportBtnButton.ButtonPushedFcn = createCallbackFcn(app, @GenReportBtnButtonPushed, true);
            app.GenReportBtnButton.Text = 'Gen Report Btn';
            app.ADMINSETTINGSTab = uitab(app.TabGroup);
            app.ADMINSETTINGSTab.Title = 'ADMIN SETTINGS';
            app.LoadModelButton = uibutton(app.ADMINSETTINGSTab, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.SMTPEditFieldLabel = uilabel(app.ADMINSETTINGSTab);
            app.SMTPEditFieldLabel.Text = 'SMTP';
            app.SMTPEditField = uieditfield(app.ADMINSETTINGSTab, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'NEUROFORGE DIAGNOSTICS: AI-POWERED DIABETIC RETINOPATHY ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TelemedicineUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end